classdef KyvGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PlayButton                   matlab.ui.control.StateButton
        UIAxes2                      matlab.ui.control.UIAxes
        ReferenceCheckBox            matlab.ui.control.CheckBox
        AngleCheckBox                matlab.ui.control.CheckBox
        SpeedCheckBox                matlab.ui.control.CheckBox
        InputCheckBox                matlab.ui.control.CheckBox
        ControlPanel                 matlab.ui.container.Panel
        ControllerLabel              matlab.ui.control.Label
        RegulatorSwitch              matlab.ui.control.Switch
        ControllerTypeDropDownLabel  matlab.ui.control.Label
        ControllerTypeDropDown       matlab.ui.control.DropDown
        PEditFieldLabel              matlab.ui.control.Label
        PEditField                   matlab.ui.control.NumericEditField
        IEditFieldLabel              matlab.ui.control.Label
        IEditField                   matlab.ui.control.NumericEditField
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.NumericEditField
        ReferenceKnobLabel           matlab.ui.control.Label
        ReferenceKnob                matlab.ui.control.Knob
        ErrorKnobLabel               matlab.ui.control.Label
        ErrorKnob                    matlab.ui.control.Knob
        PushButton                   matlab.ui.control.Button
        k1KnobLabel                  matlab.ui.control.Label
        k1Knob                       matlab.ui.control.Knob
        k2KnobLabel                  matlab.ui.control.Label
        k2Knob                       matlab.ui.control.Knob
        Button                       matlab.ui.control.Button
        ConditionsPanel              matlab.ui.container.Panel
        AngleKnobLabel               matlab.ui.control.Label
        AngleKnob                    matlab.ui.control.Knob
        dAngledtKnobLabel            matlab.ui.control.Label
        dAngledtKnob                 matlab.ui.control.Knob
        SetButton                    matlab.ui.control.Button
        PendulumsetupPanel           matlab.ui.container.Panel
        mSliderLabel                 matlab.ui.control.Label
        mSlider                      matlab.ui.control.Slider
        LSliderLabel                 matlab.ui.control.Label
        LSlider                      matlab.ui.control.Slider
        BSliderLabel                 matlab.ui.control.Label
        BSlider                      matlab.ui.control.Slider
        ResetPanel                   matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        k1CheckBox                   matlab.ui.control.CheckBox
        AngleCheckBox_2              matlab.ui.control.CheckBox
        PendulumCheckBox             matlab.ui.control.CheckBox
        setupCheckBox                matlab.ui.control.CheckBox
        speedCheckBox                matlab.ui.control.CheckBox
        ReferenceCheckBox_2          matlab.ui.control.CheckBox
        ErrorCheckBox                matlab.ui.control.CheckBox
        k2CheckBox                   matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        state = [0, 0];
        Tsim = inf;
        t = 0;
        tPlot = 0;
        dt = 3e-2;
        dtPlot = 1/20;
        output=0;
        input=0;
        m = 1;
        L = 10;
        g = 9.81;
        B = 1;
        sz1 = 0.2;
        sz2 = 1;
        sz3 = 0.2;
        k1 = 1;
        k2 = 1;
        r = 0;
        stopSim=0;
        Angle_queue=zeros(800,1);
        Speed_queue=zeros(800,1);
        Reference_queue=zeros(800,1);
        input_queue=zeros(800,1);
        plotcounter=0;
        
        %PID params
        h = 0.1; % PID parameter [!should work for every h > 0]
        p = 0; % p is the real pole locaiton
        p1=0;
        p2=0;
        p3=0;
        PIDState = [0 0]; % Integrator state and old e
        ManualPIDState = [0 0];
        ManualPIState = [0 0];
        ManualPDState = [0 0];
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        function [u]= choose_controller(app)
            value=app.RegulatorSwitch.Value;
            reg= app.ControllerTypeDropDown.Value;
            if value == "Off"
                u=0;
            else
                if reg == "I/O Feedback Lin."
                    u=linearization_law(app,control_law(app));
                elseif reg == "I/S Feedback Lin."
                    u=linearization_lawVS(app,control_law(app));
                elseif reg == "PID"
                    u=PIDAtUnstableEquilibrium(app);
                elseif reg == "Manual PID"
                    u=PIDManual(app);
                elseif reg == "Manual PI"
                    u=PIManual(app);
                elseif reg == "Manual P"
                    u=PManual(app);
                elseif reg == "Manual PD"
                    u=PDManual(app);
                end
            end
            
        end
        function [u]= PIDAtUnstableEquilibrium(app)
            I = -app.p1*app.p2*app.p3;
            P = app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
            D = -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
            
            e = app.r - app.state(1);
            de = (e - app.PIDState(2))/app.dt;
            eint = app.PIDState(1) + app.dt*e;
            u = app.m*app.L^2*(P*e + D*de + I*eint);
            
            % Update pid state
            app.PIDState(1) = eint;
            app.PIDState(2) = e;
        end
        
        function [u]= PIDManual(app)
            I = app.IEditField.Value;
            P = app.PEditField.Value;
            D = app.DEditField.Value;
            
            e = app.r - app.state(1);
            de = (e - app.ManualPIDState(2))/app.dt;
            eint = app.ManualPIDState(1) + app.dt*e;
            u = app.m*app.L^2*(P*e + D*de + I*eint);
            
            % Update pid state
            app.ManualPIDState(1) = eint;
            app.ManualPIDState(2) = e;
        end
    
        function [u]= PIManual(app)
            I = app.IEditField.Value;
            P = app.PEditField.Value;
            
            e = app.r - app.state(1);
            eint = app.ManualPIState(1) + app.dt*e;
            u = app.m*app.L^2*(P*e  + I*eint);
            
            % Update pid state
            app.ManualPIState(1) = eint;
            app.ManualPIState(2) = e;
        end
    
        function [u]= PDManual(app)
            
            P = app.PEditField.Value;
            D = app.DEditField.Value;
            
            e = app.r - app.state(1);
            de = (e - app.ManualPDState(2))/app.dt;
            u = app.m*app.L^2*(P*e + D*de );
            
            % Update pid state
            app.ManualPDState(2) = e;
        end
    
        function [u]= PManual(app)

            P = app.PEditField.Value;
            
            e = app.r - app.state(1);
            u = app.m*app.L^2*(P*e);

        end
    
        function [u] = linearization_lawVS(app,v)
            u = app.m*app.L^2*v + sin(app.state(1))*app.g*app.L ;
        end
    
        function [u] = linearization_law(app,v)
            u = app.m*app.L^2*(v + sin(app.state(1))*app.g/app.L + app.B/app.m/app.L^2*app.state(2));
        end
        
        function [v] = control_law(app)
            v = -app.k1*app.state(2) + app.k2*(app.r - app.state(1));
        end
        
        function [new_state, output] = pendulum_step(app, input)
            app.input=input;
            new_state = zeros(2,1);
            new_state(1) = app.state(1) + app.dt*app.state(2);
            new_state(2) = app.state(2) + app.dt*(input/app.m/app.L^2 - app.g/app.L*sin(new_state(1)) - app.B/app.m/app.L^2*app.state(2));
            output = new_state(1)-pi/2;
            store_data(app);
        end
    
        
        function [] = plot_pendulum(app)
            weightxy = [ app.L*cos(app.output), app.L*sin(app.output)];
            hold(app.UIAxes,'off')
            plot(app.UIAxes, [0 weightxy(1)], [0 weightxy(2)], 'k');
            hold(app.UIAxes,'on')
            circle(app, 0,0, app.sz1);
            circle(app, weightxy(1), weightxy(2), app.sz2);
            circle(app, weightxy(1), weightxy(2), app.sz3);
            axis(app.UIAxes,[(-app.L-app.sz2) (app.L+app.sz2) (-app.L-app.sz2) (app.L+app.sz2)]);
            grid(app.UIAxes,'on')
        end
        function [] = circle(app,x,y,r)
            
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(app.UIAxes, xunit, yunit, 'k');
            
        end
        function []=store_data(app)
            app.Angle_queue=[app.Angle_queue; app.state(1)];
            app.Speed_queue=[app.Speed_queue; app.state(2)];
            app.Reference_queue=[app.Reference_queue; app.r];
            app.input_queue=[app.input_queue; app.input];
            app.Angle_queue(1)=[];
            app.Speed_queue(1)=[];
            app.Reference_queue(1)=[];
            app.input_queue(1)=[];
            
        end
        
        function []=plot_data(app)
            toPlot=[];
            if app.AngleCheckBox.Value
                toPlot=[app.Angle_queue];
            end
            if app.ReferenceCheckBox.Value
                toPlot=[toPlot app.Reference_queue];
            end
            if app.SpeedCheckBox.Value
                toPlot=[toPlot app.Speed_queue];
            end
            if app.InputCheckBox.Value
                toPlot=[toPlot app.input_queue];
            end
            maxim=max(max(toPlot));
            minim=min(min(toPlot));
            
            if maxim<0.5
                maxim=0.5;
            end
            if minim>-0.5
                minim=-0.5;
            end
            
            plot(app.UIAxes2,toPlot,'LineWidth',2);
            if 0 == isempty(maxim)
                ylim(app.UIAxes2,[minim maxim]);
            end
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p=-sqrt(app.g/app.L/3) - app.h;
            app.p1=app.p;
            app.p2=app.p;
            app.p3=app.p;
            
            app.PlayButton.BackgroundColor=[0 1 0];
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, choose_controller(app));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
            
            app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
            app.IEditField.Value= -app.p1*app.p2*app.p3;
            app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
            
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.PlayButton.Text="Pause";
                app.stopSim = 0;
                app.PlayButton.BackgroundColor=[1 1 0];
                
                while app.t < app.Tsim
                    [app.state,app.output] = pendulum_step(app, choose_controller(app));
                    app.t = app.t + app.dt;
                    app.tPlot = app.tPlot + app.dt;
                    if (app.tPlot > app.dtPlot)
                        plot_pendulum(app);
                        app.tPlot = 0;
                        if mod(app.plotcounter,2)==0
                            plot_data(app);
                        end
                        app.plotcounter = app.plotcounter + 1;
                        drawnow
                    end
                    if app.stopSim
                        break
                    end
                end
            else
                app.PlayButton.Text="Play";
                app.stopSim = 1;
                app.PlayButton.BackgroundColor=[0 1 0];
            end
        end

        % Value changing function: ReferenceKnob
        function ReferenceKnobValueChanging(app, event)
            changingValue = event.Value;
            app.r=changingValue;
        end

        % Button pushed function: PushButton
        function PushButtonPushed(app, event)
            errorVal=app.ErrorKnob.Value;
            app.state(2) = app.state(2) + errorVal;
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            value = app.mSlider.Value;
            app.m=value;
            app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
            app.IEditField.Value= -app.p1*app.p2*app.p3;
            app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
        end

        % Value changed function: LSlider
        function LSliderValueChanged(app, event)
            value = app.LSlider.Value;
            app.L=value;
            app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
            app.IEditField.Value= -app.p1*app.p2*app.p3;
            app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.B=value;
            app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
            app.IEditField.Value= -app.p1*app.p2*app.p3;
            app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.k1CheckBox.Value
                app.k1=1;
                app.k1Knob.Value=app.k1;
            end
            if app.k2CheckBox.Value
                app.k2=1;
                app.k2Knob.Value=app.k2;
            end
            if app.setupCheckBox.Value
                app.m = 1;
                app.L = 10;
                app.B = 1;
                app.mSlider.Value=app.m;
                app.LSlider.Value=app.L;
                app.BSlider.Value=app.B;
                
            end
            if app.AngleCheckBox_2.Value
                app.AngleKnob.Value=0;
            end
            if app.speedCheckBox.Value
                app.dAngledtKnob.Value=0;
            end
            if app.ReferenceCheckBox_2.Value
                app.r = 0;
                app.ReferenceKnob.Value=0;
            end
            if app.ErrorCheckBox.Value
                app.ErrorKnob.Value=0;
            end       
  
            
            if app.PendulumCheckBox.Value
                app.state = [0, 0];
                app.output=app.state(1);
                app.PIDState = [0 0];
                app.ManualPIDState = [0 0];
                app.ManualPIState = [0 0];
                app.ManualPDState = [0 0];
                
                app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
                app.IEditField.Value= -app.p1*app.p2*app.p3;
                app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
                
                app.output=app.state(1);
                [app.state,app.output] = pendulum_step(app, choose_controller(app));
                app.t = app.t + app.dt;
                app.tPlot = app.tPlot + app.dt;
                plot_pendulum(app);
                app.tPlot = 0;
            end
  
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.state = [app.AngleKnob.Value, app.dAngledtKnob.Value];
            app.output=app.AngleKnob.Value;
            
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, choose_controller(app));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
        end

        % Value changed function: ControllerTypeDropDown
        function ControllerTypeDropDownValueChanged(app, event)
            value = app.ControllerTypeDropDown.Value;
            if value == "Manual PID"
                app.PEditField.Enable=1;
                app.IEditField.Enable=1;
                app.DEditField.Enable=1;
                app.k1Knob.Enable=0;
                app.k2Knob.Enable=0;
            elseif value == "Manual PI"
                app.PEditField.Enable=1;
                app.IEditField.Enable=1;
                app.DEditField.Enable=0;
                app.k1Knob.Enable=0;
                app.k2Knob.Enable=0;
            elseif value == "Manual P"
                app.PEditField.Enable=1;
                app.IEditField.Enable=0;
                app.DEditField.Enable=0;
                app.k1Knob.Enable=0;
                app.k2Knob.Enable=0;
            elseif value == "Manual PD"
                app.PEditField.Enable=1;
                app.IEditField.Enable=0;
                app.DEditField.Enable=1;
                app.k1Knob.Enable=0;
                app.k2Knob.Enable=0;
            elseif value == "PID"
                app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
                app.IEditField.Value= -app.p1*app.p2*app.p3;
                app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
                app.PEditField.Enable=0;
                app.IEditField.Enable=0;
                app.DEditField.Enable=0;
                app.k1Knob.Enable=0;
                app.k2Knob.Enable=0;
                
            else
                app.PEditField.Value= app.g/app.L + (app.p1*app.p2 + app.p2*app.p3 + app.p3*app.p1);
                app.IEditField.Value= -app.p1*app.p2*app.p3;
                app.DEditField.Value= -app.B/app.m/app.L^2 - (app.p1 + app.p2 + app.p3);
                app.PEditField.Enable=0;
                app.IEditField.Enable=0;
                app.DEditField.Enable=0;
                app.k1Knob.Enable=1;
                app.k2Knob.Enable=1;
                
            end
            
            
        end

        % Value changed function: k1Knob
        function k1KnobValueChanged(app, event)
            value = app.k1Knob.Value;
            app.k1=value;
        end

        % Value changed function: k2Knob
        function k2KnobValueChanged(app, event)
            value = app.k2Knob.Value;
            app.k2=value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.ControllerTypeDropDown.Value;
            if value == "I/O Feedback Lin."
                imshow('VVimg.jpg')
            elseif value == "Manual PI"
                imshow('PI.png')
            elseif value == "Manual P"
                imshow('proportional_controller.jpg')
            elseif value == "I/S Feedback Lin."
                imshow('VSimg.png')
            else
                imshow('PID.png')
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'y')
            app.ReferenceCheckBox = uicheckbox(app.UIFigure);
            app.ReferenceCheckBox.Text = 'Reference';
            app.AngleCheckBox = uicheckbox(app.UIFigure);
            app.AngleCheckBox.Text = 'Angle';
            app.AngleCheckBox.Value = true;
            app.SpeedCheckBox = uicheckbox(app.UIFigure);
            app.SpeedCheckBox.Text = 'Speed';
            app.InputCheckBox = uicheckbox(app.UIFigure);
            app.InputCheckBox.Text = 'Input';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.ControllerLabel = uilabel(app.ControlPanel);
            app.ControllerLabel.Text = 'Controller';
            app.RegulatorSwitch = uiswitch(app.ControlPanel, 'slider');
            app.ControllerTypeDropDownLabel = uilabel(app.ControlPanel);
            app.ControllerTypeDropDownLabel.Text = 'Controller Type';
            app.ControllerTypeDropDown = uidropdown(app.ControlPanel);
            app.ControllerTypeDropDown.Items = {'I/O Feedback Lin.', 'I/S Feedback Lin.', 'PID', 'Manual PID', 'Manual PI', 'Manual PD', 'Manual P'};
            app.ControllerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeDropDownValueChanged, true);
            app.ControllerTypeDropDown.Value = 'I/O Feedback Lin.';
            app.PEditFieldLabel = uilabel(app.ControlPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PEditField.Enable = 'off';
            app.IEditFieldLabel = uilabel(app.ControlPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ControlPanel, 'numeric');
            app.IEditField.Enable = 'off';
            app.DEditFieldLabel = uilabel(app.ControlPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ControlPanel, 'numeric');
            app.DEditField.Enable = 'off';
            app.ReferenceKnobLabel = uilabel(app.ControlPanel);
            app.ReferenceKnobLabel.Text = 'Reference';
            app.ReferenceKnob = uiknob(app.ControlPanel, 'continuous');
            app.ReferenceKnob.Limits = [0 6.28318530717959];
            app.ReferenceKnob.ValueChangingFcn = createCallbackFcn(app, @ReferenceKnobValueChanging, true);
            app.ErrorKnobLabel = uilabel(app.ControlPanel);
            app.ErrorKnobLabel.Text = 'Error';
            app.ErrorKnob = uiknob(app.ControlPanel, 'continuous');
            app.ErrorKnob.Limits = [-3 3];
            app.PushButton = uibutton(app.ControlPanel, 'push');
            app.PushButton.ButtonPushedFcn = createCallbackFcn(app, @PushButtonPushed, true);
            app.PushButton.Text = 'Push';
            app.k1KnobLabel = uilabel(app.ControlPanel);
            app.k1KnobLabel.Text = 'k1';
            app.k1Knob = uiknob(app.ControlPanel, 'continuous');
            app.k1Knob.Limits = [-5 5];
            app.k1Knob.ValueChangedFcn = createCallbackFcn(app, @k1KnobValueChanged, true);
            app.k1Knob.Value = 1;
            app.k2KnobLabel = uilabel(app.ControlPanel);
            app.k2KnobLabel.Text = 'k2';
            app.k2Knob = uiknob(app.ControlPanel, 'continuous');
            app.k2Knob.Limits = [-5 5];
            app.k2Knob.ValueChangedFcn = createCallbackFcn(app, @k2KnobValueChanged, true);
            app.k2Knob.Value = 1;
            app.Button = uibutton(app.ControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.ConditionsPanel = uipanel(app.UIFigure);
            app.ConditionsPanel.Title = 'Conditions';
            app.AngleKnobLabel = uilabel(app.ConditionsPanel);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.ConditionsPanel, 'continuous');
            app.AngleKnob.Limits = [0 6.28318530717959];
            app.dAngledtKnobLabel = uilabel(app.ConditionsPanel);
            app.dAngledtKnobLabel.Text = 'dAngle/dt';
            app.dAngledtKnob = uiknob(app.ConditionsPanel, 'continuous');
            app.dAngledtKnob.Limits = [-2 2];
            app.SetButton = uibutton(app.ConditionsPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PendulumsetupPanel = uipanel(app.UIFigure);
            app.PendulumsetupPanel.Title = 'Pendulum setup';
            app.mSliderLabel = uilabel(app.PendulumsetupPanel);
            app.mSliderLabel.Text = 'm';
            app.mSlider = uislider(app.PendulumsetupPanel);
            app.mSlider.Limits = [0.1 5];
            app.mSlider.MajorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.mSlider.MajorTickLabels = {'0.1', '0.5', '1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5'};
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.mSlider.Value = 1;
            app.LSliderLabel = uilabel(app.PendulumsetupPanel);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.PendulumsetupPanel);
            app.LSlider.Limits = [1 50];
            app.LSlider.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @LSliderValueChanged, true);
            app.LSlider.Value = 10;
            app.BSliderLabel = uilabel(app.PendulumsetupPanel);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.PendulumsetupPanel);
            app.BSlider.Limits = [0.05 1.5];
            app.BSlider.MajorTicks = [0.05 0.15 0.3 0.45 0.6 0.75 0.9 1.05 1.2 1.35 1.5];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.Value = 1;
            app.ResetPanel = uipanel(app.UIFigure);
            app.ResetPanel.Title = 'Reset Panel';
            app.ResetButton = uibutton(app.ResetPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.k1CheckBox = uicheckbox(app.ResetPanel);
            app.k1CheckBox.Text = 'k1';
            app.k1CheckBox.Value = true;
            app.AngleCheckBox_2 = uicheckbox(app.ResetPanel);
            app.AngleCheckBox_2.Text = 'Angle';
            app.AngleCheckBox_2.Value = true;
            app.PendulumCheckBox = uicheckbox(app.ResetPanel);
            app.PendulumCheckBox.Text = 'Pendulum';
            app.PendulumCheckBox.Value = true;
            app.setupCheckBox = uicheckbox(app.ResetPanel);
            app.setupCheckBox.Text = 'setup';
            app.setupCheckBox.Value = true;
            app.speedCheckBox = uicheckbox(app.ResetPanel);
            app.speedCheckBox.Text = 'speed';
            app.speedCheckBox.Value = true;
            app.ReferenceCheckBox_2 = uicheckbox(app.ResetPanel);
            app.ReferenceCheckBox_2.Text = 'Reference';
            app.ReferenceCheckBox_2.Value = true;
            app.ErrorCheckBox = uicheckbox(app.ResetPanel);
            app.ErrorCheckBox.Text = 'Error';
            app.ErrorCheckBox.Value = true;
            app.k2CheckBox = uicheckbox(app.ResetPanel);
            app.k2CheckBox.Text = 'k2';
            app.k2CheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = KyvGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end